classdef ALEv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IR_Equalisation               matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        PlotData3                     matlab.ui.control.CheckBox
        Label_4                       matlab.ui.control.Label
        PlotData2                     matlab.ui.control.CheckBox
        Label_3                       matlab.ui.control.Label
        PlotData1                     matlab.ui.control.CheckBox
        Label_2                       matlab.ui.control.Label
        PlotData4                     matlab.ui.control.CheckBox
        loadmicCorrection             matlab.ui.control.Button
        octavesmoothingDropDown       matlab.ui.control.DropDown
        octavesmoothingDropDownLabel  matlab.ui.control.Label
        FIRtapsEditField              matlab.ui.control.NumericEditField
        FIRtapsEditFieldLabel         matlab.ui.control.Label
        filtertypeDropDown            matlab.ui.control.DropDown
        filtertypeDropDownLabel       matlab.ui.control.Label
        saveIRsButton                 matlab.ui.control.Button
        saveFiltercoeffButton         matlab.ui.control.Button
        windowlength                  matlab.ui.control.NumericEditField
        windowlengthinsamplesLabel    matlab.ui.control.Label
        saveavgIRButton               matlab.ui.control.Button
        highestpoleinHzDropDown       matlab.ui.control.DropDown
        highestpoleinHzDropDownLabel  matlab.ui.control.Label
        lowestpoleinHzDropDown        matlab.ui.control.DropDown
        lowestpoleinHzDropDownLabel   matlab.ui.control.Label
        loadIRButton                  matlab.ui.control.Button
        calculateFilterButton         matlab.ui.control.Button
        saveResultsButton             matlab.ui.control.Button
        resetSettingsButton           matlab.ui.control.Button
        saveEQButton                  matlab.ui.control.Button
        SettingsLabel                 matlab.ui.control.Label
        measurementButton             matlab.ui.control.Button
        OutputDevice                  matlab.ui.control.DropDown
        OutputDeviceLabel             matlab.ui.control.Label
        InputDevice                   matlab.ui.control.DropDown
        InputDeviceLabel              matlab.ui.control.Label
        readSettingsButton            matlab.ui.control.Button
        filtersperOctave              matlab.ui.control.NumericEditField
        nfiltersperOctaveLabel        matlab.ui.control.Label
        lowCutTargetResponse          matlab.ui.control.NumericEditField
        LowCutfortargetresponseinHZLabel  matlab.ui.control.Label
        IRmeasurements                matlab.ui.control.NumericEditField
        nofIRmeasurementsLabel        matlab.ui.control.Label
        Sweeplength                   matlab.ui.control.NumericEditField
        sweeplengthinsLabel           matlab.ui.control.Label
        estimatedRT60                 matlab.ui.control.NumericEditField
        estimatedRT60insLabel         matlab.ui.control.Label
        SamplingRate                  matlab.ui.control.DropDown
        samplingrateLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        UIIR_measured                 matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        audioDeviceInfo         % Audio Device Info
        fs                      % Sampling Rate
        RT60                    % estimated RT60
        sweeplength             % sweeplength
        numberMeasurements      % number of IR measurements
        LC                      % Low Cut for target IR response
        filtersperOct           % filters per Octave
        inputDevice             % target Input Device
        outputDevice            % target Output Device
        sweep                   % exponential sweep
        inverseSweep            % inverse sweep
        newStartFrequency       % new start Frequency
        multiIR                 % array of recorded IR's
        equalizer               % equalizer
        ir_norm                 % averaged IR
        defaultPoles            % Description
        winlength               % windowlength [samples]
        minimp                  % minimum phase response
        f                       % frequency of plot
        Am                      % Filter denominator coefficients
        Bm                      % Filter numerator coefficients
        FIR                     % Filter FIR Coefficients
        IRLoaded                % IR is loaded
        IRlen                   % # of samples
        micCorr                 % Mic Reference
    end

    methods (Access = private)
    
        function importmenu(app)
            qitem = uimenu(app.Menu,'Text','Exit');
            qitem.MenuSelectedFcn = @Exit;
            function Exit(src,event)
                close(app.IR_Equalisation);
            end
        end 
        
        %% auxiliary function
        %
        function [sweep, inverseSweep, newStartFrequency] = createSweep (app,lengthInSeconds, samplingFrequency, startFrequency)
            % Author D. Rudrich
            % 
            N = ceil (lengthInSeconds * samplingFrequency);
            w0 = 2 * pi * startFrequency / samplingFrequency;
            w1 = pi;                                                                                                    % sweeping up to Nyquist frequency
            w0 = findOptimalW0 (app,N, w0, w1);
            newStartFrequency = w0 * samplingFrequency / (2 * pi);
            n = (0 : N - 1)';                                                                                           % sample indices
            b = w1 / w0;                                                                                                % ratio between start and stop frequency
            c = (N - 1) * w0 / log(b);
            sweep = sin(c * (b .^ (n / (N - 1)) - 1));                                                                  % calculate sweep
            w =  w0 * b .^ (n / (N - 1));                                                                               % calculate inverse sweep
            inverseSweep = flipud (sweep .*  w) * 2 / sum(w);
        end

        function plotTimeIR(app,IR,loadFlag)
                if loadFlag
                    irNorm = IR(:,:) ./ max(abs(IR),[],1);
                    [pks,locs] = findpeaks(abs(irNorm(:,1)));                                                                             % find first peak to align the impulse responses
                    delay = locs(find(pks == max(pks)));
                    for i = 1:size(IR,2)
                        app.multiIR(:,i) = irNorm(delay - 10:end,i);
                        plot(app.UIIR_measured,app.multiIR(:,i))
                        hold(app.UIIR_measured,'on')
                    end
                else
                    plot(app.UIIR_measured,IR)
                    hold(app.UIIR_measured,'on')
                end
                    xlim(app.UIIR_measured,[0 200])
                    ylim(app.UIIR_measured,[-1.2 1.2])
        end
        
        function w0 = findOptimalW0 (app,N, w0, w1)
            % Author D. Rudrich
            % find optimal start frequency with gradient descent
            % to start and end sweep with zero-phase -> amplitude of zero
            for it = 1 : 15
                a = w0;
                b = w1 / w0;
                %w = a * b .^ (n / (N - 1));
                %w = a * exp(log(b) * (n / (N-1)));
                c = (N-1) * a ./ log(b);
                phi1 = c .* (b - 1);
                deltaphi = phi1 - round(phi1 / pi) * pi;
                dphidw0 = (N - 1) * (w0 * log (w1 / w0) + w0 - w1) / (w0 * log(w1 / w0)^2);
                dw0 = deltaphi / dphidw0;
                w0 = w0 + dw0;
                w0_verlauf(1,it) = w0;
            end
        end
        
        function [ImpMin] = averageIR(app,IRMatrix, fs, n, mic_corr)
            % averaging of impulse response in frequency domain
            %
            if nargin < 3 
                n = 12;
            end
            len = size(IRMatrix,1);
            number = size(IRMatrix, 2);
            HIR = fft(IRMatrix,app.fs);
            magAv = sum(abs(HIR.^2).*mic_corr, 2)./number;
            magAvNb = nbandfilterung(app,magAv,app.fs,app.fs, n);                                                
            magAvNorm = magAvNb.^(1/2);
            %phase = angle(HIR);
            %phaseAv = sum(exp(1j.*phase), 2)./number;
            %phasor = phaseAv./abs(phaseAv);
            %irAv = ifft(magAvNorm.*phasor, app.fs);
            %irAv = real(irAv);
            h = real(ifft(log(magAvNorm)));
            hc(1) = h(1);
            hc(2:app.fs/2) = 2*h(2:app.fs/2);
            hc(app.fs/2+1) = h(app.fs/2+1);
            hc(app.fs/2+2:app.fs) = 0;
            lnHmp = fft(hc, app.fs);
            Hmp = exp(lnHmp);
            ImpMin = real(ifft(Hmp)'); 
        end
        
        function [output] = nbandfilterung(app, H_ir, NFFT, fs, n)
            f = linspace(0,app.fs/2, NFFT/2+1);
            f(1) = f(2)/4;
            fc = 25*2.^(0:1/n:9.9);
            H = zeros(NFFT/2+1, length(fc)); 
            for k = 1:length(fc)
                nthoctaves = log2(f/fc(k))*n;
                upper = 1.0*(k<length(fc));
                lower = -1.0*(k>1);
                nthoctaves = max(min(nthoctaves, upper), lower);
                H(:,k) = cos(nthoctaves*pi/2).^2;
            end
            %H = H./sqrt(fc);
            H = [H ; flipud(H(2:end-1,:))];
            w = H*sum(H', 2);                                                                                       % Deviation from unity gain
            H = H./sqrt(w);
            output = H*(H'*H_ir);
        end
        
        function [y] = parfilt(app,Bm,Am,FIR,x)
            % Balázs Bank
            % http://home.mit.bme.hu/~bank/parfilt/
            %
            y=zeros(size(x));
            s=size(Am);
            for k=1:s(2)
                y=y+filter(Bm(:,k),Am(:,k),x);
            end
            y=y+filter(FIR,1,x);
        end
        
        function [Bm,Am,FIR] = parfiltid(app,in,out,p,NFIR)
            % Balázs Bank
            % http://home.mit.bme.hu/~bank/parfilt/
            %
            if nargin==3
                NFIR=1;
            end
            
            p=p(find(p)); %we don't want to have any poles in the origin - for that we have the parallel FIR part
            for k=1:length(p), %making the filter stable by flipping the poles into the unit cirle
               if abs(p(k))>1			
                  p(k)=1/conj(p(k));
              end;
            end;
            
            p=cplxpair(p); %order it to complex pole pairs + real ones afterwards
            
            %in order to have second-order sections only (i.e., no first order)
            pnum=length(p); %number of poles
            ppnum=2*floor(pnum/2); %the even part of pnum
            ODD=0;
            if pnum>ppnum, %if pnum is odd
                ODD=1;
            end;
            in=in(:); %making column vectors
            out=out(:);
            
            OUTL=length(out);
            INL=length(in);
            
            %making input the same length as the output
            if INL>OUTL,
               in=in(1:OUTL);
            end;
            
            if INL<OUTL,
               in=[in; zeros(OUTL-INL,1)];
            end;
            L=OUTL;
            
            %constructing the modeling signal matrix
            for k=1:2:ppnum-1, %second-order sections
                resp=filter(1,poly(p(k:k+1)),in); %impluse response of the two-pole filter
                M(:,k)=resp;
                M(:,k+1)=[0 ;resp(1:L-1)]; %the response delayed by one sample
            end;
            
            if ODD, %if the number of poles is odd, we have a first-order section
                resp=filter(1,poly(p(pnum)),in);
                M(:,pnum)=resp;
            end;
             
            for k=1:NFIR, %parallel FIR part
                M(:,pnum+k)=[zeros(k-1,1); in(1:L-k+1)]; 
            end;
            
            y=out; %making it column vector
            %Looking for min(||y-M*par||) as a function of par:
            %least squares solution by equation solving 
            A=M'*M;
            b=M'*y;
            par=A\b;
            
            %constructing the Bm and Am matrices
            for k=1:ppnum/2,
                Am(:,k)=poly(p(2*k-1:2*k)).';
                Bm(:,k)=par(2*k-1:2*k);
            end;
            if ODD, %we extend the first-order section to a second-order one by adding zero coefficients
                Am(:,k+1)=[poly(p(pnum)).'; 0];
                Bm(:,k+1)=[par(pnum); 0];
            end;
            
            FIR=0;
            %constructing the FIR part
            if NFIR>0,
                FIR=par(pnum+1:pnum+NFIR);
            end;
            
        end

        function [bm] = filterFIR(app,ir,target)
            %function returns the fir filter coefficients, equalizing
            %an abitrary frequency response to a target response using LMS. 
            %Parameters:
            %ir...measured impulse response, works best, if minimum phase version
            %target...target impulse response, where the filter optimizes to
            %tap...number of filter coeefficients of the resulting filter
            %linphase...If true,resulting filter coefficients are minimum phase
            %offset... optional offset in dB to raise or decrease the level of the
            %           resulting filter
            %
            % ir...dim(NFFT,1)-----doubles
            % target...dim(NFFT,1)-doubles 
            % tap...dim(1,1)-------double
            % linphase...dim(1,1)--Logical true or false
            % offset...dim(1,1)----double in dB
        
            % make it minimum phase , if not defined
            if nargin < 4
                linphase = true; 
            end
            nFIR = app.FIRtapsEditField.Value;
            % Assemble convolution matrix, consisting of delayed measured ir's
            H = zeros(length(ir)+nFIR-1,nFIR);
            for i=1:nFIR
                H(i:length(ir)+i-1,i) = ir;
            end
            % LMS algorithm
            A=H'*H;
            b=H'*target;
            bm=A\b;
            bm = bm/max(bm);
            idx = round(1000/(44100/app.fs)); % idx of 1000Hz for offset
            res = db(fft(conv(bm ,ir),app.fs));
            dB_val = (-1)*res(idx);
            bm= bm * 10^(dB_val/20);
        end

        function [fir_linp] = filterFIRLin(app,ir,target)
            %function returns the linear-phase fir filter coefficients, equalizing
            %an abitrary frequency response to a target response using LMS. 
            %Parameters: 
            %ir...measured impulse response, works best, if minimum phase version
            %target...target impulse response, where the filter optimizes to
            %tap...number of filter coeefficients of the resulting filter
            %offset... optional offset in dB to raise or decrease the level of the
            %           resulting filter
            %
            % ir...dim(NFFT,1)-----doubles
            % target...dim(NFFT,1)-doubles 
            % tap...dim(1,1)-------double
            % offset...dim(1,1)----double in dB

            % create inversed version of Magnitude
            t_filt = abs(fft(target,app.fs));
            H_inv = t_filt.*(1./abs(fft(ir,app.fs)));
            L = app.FIRtapsEditField.Value/2; %
            % create A matrix with sinusiods
            A = zeros(app.fs, L+1);
            A(:,1) = 2*ones(app.fs,1);
            w = linspace(0,2*pi,app.fs);
            for i=1:app.fs
                for j = 2:L+1
                    A(i,j) = 2*cos(w(i)*(j-1));
                end
            end
            % LMS algorithm
            A_=(A'*A);
            b=A'*H_inv;
            bm=A_\b;
            % make the fir symmetric
            fir_linp =[flipud(bm(2:end)); 2*bm(1);bm(2:end)]/2;
            idx = round(1000/(44100/app.fs)); % idx of 1000Hz for offset
            res = db(fft(conv(fir_linp ,ir),app.fs));
            dB_val = (-1)*res(idx);
            disp(dB_val);
            fir_linp= fir_linp * 10^(dB_val/20);
            w = tukeywin(length(fir_linp),0.1);
            fir_linp = fir_linp.*w;
        end

        function target = targetIR(app,len,NFFT,LC,HC)
            dirac = zeros(len,1);
            dirac(1) = 1;
            if nargin >= 4 
                [Bf,Af]=butter(4,LC/(NFFT/2),'high'); 
                dirac=filter(Bf,Af,dirac); 
            end
            if nargin == 5
                [Bf,Af]=butter(4,HC/(NFFT/2),'low'); 
                dirac=filter(Bf,Af,dirac); 
            end
            target = dirac; 
        end

        function [TF,del] = getTF(app,h_out, NFFT)
           TF = zeros(NFFT,size(h_out,2));
           for i=1:size(h_out,2)
               [pk, del] = max(abs(h_out));
               h_in = zeros(length(h_out),1);
               h_in(del)=1;
               OUT = fft(h_out(:,i),NFFT);
               IN = fft(h_in,NFFT);
               TF(:,i) = OUT./IN;  
           end
        end

        function [logscale,logmagn]=tfplot(app,data,linewidth,color,Fs,octbin,avg,window)
            % Balázs Bank
            % http://home.mit.bme.hu/~bank/parfilt/
            [c, data] = rceps(data);
            if nargin<8
                window='halfhanning';
            end
            if nargin<7
                avg='comp';
            end
            if nargin<6
                octbin=100;
            end
            if nargin<5
                Fs=44100;
            end
            if nargin<4
                color='b';
            end
            FFTSIZE=2^18;
            data=data(:);
            logfact=2^(1/octbin);
            LOGN=floor(log(Fs/2)/log(logfact));
            logscale=logfact.^[0:LOGN]; %logarithmic scale from 1 Hz to Fs/2
            app.f = logscale; 
            if strcmpi(window,'nowindow') % no windowing
                tf=getTF(app,data, FFTSIZE); %FFT
            else 
                WL=length(data); %creating a half hanning window
                hann=hanning(WL*2);
                endwin=hann(WL+1:2*WL);
                tf=getTF(app,data.*endwin, FFTSIZE);  %FFT
            end;
            compamp=tf(1:FFTSIZE/2);
            fstep=Fs/FFTSIZE;
            for k=0:LOGN,
               %finding the start and end positions of the logaritmic bin
               start=round(logscale(k+1)/sqrt(logfact)/fstep); 
               start=max(start,1);
               start=min(start,FFTSIZE/2);
               stop=round(logscale(k+1)*sqrt(logfact)/fstep)-1;
               stop=max(stop,start);
               stop=max(stop,1);
               stop=min(stop,FFTSIZE/2);
               if strcmpi(avg,'comp'),   %averaging the complex transfer function
                   logmagn(k+1)=mean(compamp(start:stop)); 
               end;
               if strcmpi(avg,'abs'),
                  logmagn(k+1)=mean(abs(compamp(start:stop))); 
               end;
               if strcmpi(avg,'power'),
                   logmagn(k+1)=sqrt(mean(abs(compamp(start:stop)).^2)); 
               end;  
            end; 
            if nargout<1
               h = semilogx(app.UIAxes,logscale,20*log10(abs(logmagn)),'--','color', color);
               set(h(1),'linewidth',linewidth);
            end
        end
        
        function [logscale,smoothmagn]=tfplots(app,data,linewidth,color,Fs,fract,avg,window)
            % Balázs Bank
            % http://home.mit.bme.hu/~bank/parfilt/
            octbin=100; % this is how many frequency bins are computed per octave
            [c, data] = rceps(data);
            if nargin<8,
                window='halfhanning';
            end;
            if nargin<7,
                avg='power';
            end;
            if nargin<6,
                fract=6;
            end;
            if nargin<5,
                Fs=44100;
            end;
            if nargin<4,
                color='b';
            end;
            FFTSIZE=2^18;
            data=data(:);
            logfact=2^(1/octbin);
            LOGN=floor(log(Fs/2)/log(logfact));
            logscale=logfact.^[0:LOGN]; %logarithmic scale from 1 Hz to Fs/2
            if strcmpi(window,'nowindow'), % no windowing
                tf=getTF(app,data,FFTSIZE); %FFT
            else 
                WL=length(data); %creating a half hanning window
                hann=hanning(WL*2);
                endwin=hann(WL+1:2*WL);
                tf=getTF(app,data.*endwin,FFTSIZE); %FFT
            end;
            magn=(abs(tf(1:FFTSIZE/2)));
            compamp=tf(1:FFTSIZE/2);
            %creating 100th octave resolution log. spaced data from the lin. spaced FFT data
            clear logmagn;
            fstep=Fs/FFTSIZE;
            for k=0:LOGN,
               start=round(logscale(k+1)/sqrt(logfact)/fstep);
               start=max(start,1);
               start=min(start,FFTSIZE/2);
               stop=round(logscale(k+1)*sqrt(logfact)/fstep);
               stop=max(stop,1);
               stop=min(stop,FFTSIZE/2);
            
               if strcmpi(avg,'comp') | strcmpi(avg,'complex'),   %averaging the complex transfer function
                   logmagn(k+1)=mean(compamp(start:stop)); 
               end;
               if strcmpi(avg,'abs'), %averaging absolute value
                  logmagn(k+1)=mean(abs(compamp(start:stop))); 
               end;
               if strcmpi(avg,'power') | strcmpi(avg,'pow'), %averaging power
                   logmagn(k+1)=sqrt(mean(abs(compamp(start:stop)).^2)); 
               end;    
            end;
            %creating hanning window
            HL=2*round(octbin/fract); %fractional octave smoothing
            hh=hanning(HL);
            L=length(logmagn);
            logmagn(L+1:L+HL)=0;
            
            %Smoothing the log. spaced data by convonvling with the hanning window
               if strcmpi(avg,'comp') | strcmpi(avg,'complex'),   %averaging the complex transfer function
                   tmp=fftfilt(hh,logmagn); 
                   smoothmagn=tmp(HL/2+1:HL/2+L)/sum(hh);
               end;
               if strcmpi(avg,'abs'), %averaging absolute value
                   tmp=fftfilt(hh,logmagn);
                   smoothmagn=tmp(HL/2+1:HL/2+L)/sum(hh);
               end;
               if strcmpi(avg,'power') | strcmpi(avg,'pow'), %averaging power
                   tmp=fftfilt(hh,logmagn.^2); 
                   smoothmagn=sqrt(tmp(HL/2+1:HL/2+L)/sum(hh));
               end;
            %plotting
            if nargout<1,
                h = semilogx(app.UIAxes, logscale,20*log10(abs(smoothmagn)),'color', color);
                set(h(1),'linewidth',linewidth);
            end;
        end

        function [p]=freqpoles(app,fr,Fs,Q)
            %   C. Balazs Bank, 2010.
            %   
            if nargin<3                                                                                                % default sampling rate
                Fs=44100;      
            end
            fr=fr(:);                                                                                                  % making a column vector
            wp=2*pi*fr/Fs;                                                                                             % discrete pole frequencies     
            if nargin<4                                                                                                % we compute the bandwidth from the pole frequencies
                wp=sort(wp);                                                                                           % sorting the frequencies
                pnum=length(wp); 
                dwp=zeros(pnum,1);
                for k=2:pnum-1
                    dwp(k)=(wp(k+1)-wp(k-1))/2;
                end
                dwp(1)=(wp(2)-wp(1));
                dwp(pnum)=(wp(pnum)-wp(pnum-1));
            end
            if nargin==4                                                                                               % since Q are given, we compute the bandwidth from Q
                Q=Q(:);                                                                                                % making a column vector
                dwp=wp./Q;
            end
            p=exp(-dwp/2).*exp(1i*wp);                                                                                 % computing poles from center frequency wp and bandwidth dwp
            p=[p; conj(p)];                                                                                            % pole pairs one after the other
            p=p(:);                                                                                                    % making it a column vector      
        end

        function [win] = window(app,wlen)
            % creates modified hann window
            %
            win = zeros(wlen,1);
            len4 = floor(wlen/4);
            len2 = floor(wlen/2);
            hwin = hann(len2);
            win(1:len4) = 1;
            win(len4+1:len2+1) = hwin(len4:end);
        end

        function [fplog,plog] = logPoles(app)
            % calculate logarithmic spaced poles 
            %
            app.LC = app.lowCutTargetResponse.Value;
            app.filtersperOct = app.filtersperOctave.Value;
            tLC = 15.625;
            tHC = 32000;
            nPoles = 11*24+1;
            fplog = logspace(log10(tLC),log10(tHC),nPoles);
            nPolesNew = 11 * app.filtersperOct + 1;
            LC = str2double(app.lowestpoleinHzDropDown.Value);
            HC = str2double(app.highestpoleinHzDropDown.Value);
            start = find(round(fplog,1) == round(LC,1));
            stop = find(round(fplog,1) == round(HC,1));
            fplogNew = [logspace(log10(tLC),log10(tHC),nPolesNew)];
            startNew = find(round(fplogNew,1) == round(LC,1));        
            stopNew = find(round(fplogNew,1) == round(HC,1));
            if round(fplog(start),1) == round(fplogNew(startNew),1)
               s = startNew;
            else
                [~,idx] = min(abs(fplogNew-fplog(start)));
                s = idx;
            end
            if round(fplog(stop),1) == round(fplogNew(stopNew),1)
               st = stopNew;
            else
                [~,idx] = min(abs(fplogNew-fplog(stop)));
                st = idx;
            end           
            fplog = fplogNew(s:st);
            plog = freqpoles(app,fplog);
        end

        
        %% main functions
        %
        function Measurement(app)
            bits = 24;                                                                                                  % Initialisation
            recch = 1;
            [app.sweep, app.inverseSweep, app.newStartFrequency] = createSweep(app,app.sweeplength,app.fs,20);          % create sweep
            rec = audiorecorder(app.fs, bits, recch, app.inputDevice);                                                  % set up matlab player and recorder
            pla = audioplayer(0.99 * app.sweep, app.fs, bits, app.outputDevice);
            delayP = pla.TimerPeriod;
            delayC = 0.0131;
            recSamples = floor((app.sweeplength + app.RT60 + delayP + delayC) * app.fs);
            app.multiIR = zeros(recSamples+length(app.inverseSweep), app.numberMeasurements);
            for ii = 1:app.numberMeasurements                                                                           % loop for # of measurements
                record(rec);                                                                                            % record and play sweep
                play(pla);
                pause(app.sweeplength+app.RT60)
                stop(rec);
                recSweep = getaudiodata(rec);
                ir = conv(recSweep, app.inverseSweep);                                                                  % calculate impulse response with recorded and inverse sweep
                irNorm = ir./max(abs(ir));
                [pks,locs] = findpeaks(ir);                                                                             % find first peak to align the impulse responses
                delay = locs(find(pks == max(pks)));
                irNorm = irNorm(delay - 10:delay+app.RT60*app.fs);                                                      % truncate impulse response
                app.multiIR(1:size(irNorm,1),ii) = irNorm;
                app.windowlength.Limits = [1 size(irNorm,1)];
                app.windowlength.Value = floor(size(irNorm,1)/4);
                app.windowlength.Limits = [1 size(irNorm,1)];
                app.windowlength.Value = floor(size(irNorm,1)/4);
                plotTimeIR(app,irNorm, false)
                if ii ~= app.numberMeasurements
                    uiwait(msgbox('move microphone and press ->Ok<- for the next measurement','Measurement Completed!','modal'));
                else
                    uiwait(msgbox('Measurement Completed!'));
                end
            end
            hold(app.UIIR_measured, 'off');
            app.calculateFilterButton.Enable = 'on';
        end

        function filterCalculation(app)
            %% calculate logarithmic spaced poles 
            %
            app.LC = app.lowCutTargetResponse.Value;
            app.filtersperOct = app.filtersperOctave.Value;
            [fplog,plog] = logPoles(app);
            %% averaging of impulse responses
            % 
            app.ir_norm = averageIR(app,app.multiIR,app.fs,str2double(app.octavesmoothingDropDown.Value),app.micCorr);
            app.saveavgIRButton.Enable = 'on';
            if ~strcmp(app.filtertypeDropDown.Value,'parallel - IIR')
                app.windowlength.Value = app.FIRtapsEditField.Value;
            end
            win = window(app,app.windowlength.Value);
            app.ir_norm = app.ir_norm(1:app.windowlength.Value).*win;
            data = app.ir_norm;
            %% minimum phase of impulse response
            % 
            minresp = data; 
            len = length(minresp) -1 + app.FIRtapsEditField.Value;
            outf = targetIR(app,len,app.fs,app.LC);
            imp=zeros(1,length(data));
            imp(1)=1;
            %% create filter
            %
            if strcmp('parallel - IIR',app.filtertypeDropDown.Value)
                [app.Bm,app.Am,app.FIR]=parfiltid(app, minresp,outf,plog,1);
                 equalizedresp=parfilt(app,app.Bm,app.Am,app.FIR,data);
                 app.equalizer=parfilt(app, app.Bm,app.Am,app.FIR,imp);
            end
            if strcmp('FIR',app.filtertypeDropDown.Value)
                [app.FIR]=filterFIR(app, minresp,outf);
                equalizedresp=conv(app.FIR,data);
                app.equalizer = app.FIR;
                app.Am = 1;
            end
            if strcmp('linearphase - FIR',app.filtertypeDropDown.Value)
                [app.FIR]=filterFIRLin(app, minresp,outf);
                equalizedresp=conv(app.FIR,data);
                app.equalizer = app.FIR;
                app.Am = 1;              
            end


            %% ploting all results
            %
            hold(app.UIAxes, 'on')
            leg = {};
            if app.PlotData1.Value == 1
                tfplot(app, data, 0.5,'#7E2F8E',app.fs);                                                                  % original loudspeaker-room response
                tfplots(app, data, 2,'#7E2F8E',app.fs,str2double(app.octavesmoothingDropDown.Value));                           % 3rd octave smoothed
                leg{end+1} = '';
                leg{end+1} = 'measured TF';
            end
            if app.PlotData2.Value == 1
                tfplot(app, equalizedresp,0.5, '#EDB120', app.fs);                                                        % equalized loudspeaker-room response
                tfplots(app, equalizedresp,2,'#EDB120', app.fs,str2double(app.octavesmoothingDropDown.Value));                  % 3rd octave smoothed
                leg{end+1} = '';
                leg{end+1} = 'equalized TF';
            end
            if app.PlotData3.Value == 1
                tfplot(app, app.equalizer,0.5,'#D95319', app.fs);                                                         % equalizer transfer function
                tfplots(app, app.equalizer,2,'#D95319', app.fs,str2double(app.octavesmoothingDropDown.Value));                  % 3rd octave smoothed
                leg{end+1} = '';
                leg{end+1} = 'filter TF';
            end    
            if app.PlotData4.Value == 1
                semilogx(app.UIAxes, db(app.micCorr(1:app.fs/2))*(-1),'Color', [0.388235294117647, 0.721568627450980, 1], 'LineWidth', 2) 
                leg{end+1} = 'mic Correction';
                
            end
            if strcmp('parallel - IIR',app.filtertypeDropDown.Value)
                plot(app.UIAxes, fplog, (-30)*ones(1,length(fplog)),'o','color', 'k','MarkerSize',10, 'Linewidth',2.5)
                leg{end+1} = 'pole location';
            end
            %leg = {'','measured TF','', 'equalized TF', '', 'filter TF', 'mic Correction', 'pole location'};
            legend(app.UIAxes,leg, "location",'north', NumColumns=5, Fontsize=16,Interpreter='latex', AutoUpdate='off');
            hold(app.UIAxes, 'off')
            axis(app.UIAxes, [10 25000 -40 30]);
            %% ploting legend 
            xticks(app.UIAxes, [20,50,100, 200, 400, 1000, 2000, 4000, 8000, 16000])
            app.saveEQButton.Enable = 'on';
            app.saveResultsButton.Enable = 'on';
            app.saveFiltercoeffButton.Enable = 'on';
            app.resetSettingsButton.Enable = 'on';
            if ~app.IRLoaded
                app.saveIRsButton.Enable = 'on';
            end 
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IR_Equalisation.Name = 'Automatic Loudspeaker Equalization';
            importmenu(app);
            app.audioDeviceInfo = audiodevinfo;
            app.InputDevice.Items = {app.audioDeviceInfo.input.Name};
            app.OutputDevice.Items = {app.audioDeviceInfo.output.Name};
            app.measurementButton.Enable = 'off';
            app.saveEQButton.Enable = 'off';
            app.saveEQButton.Tooltip = ['the equalizer impulse response is saved as .wav file.'];
            app.resetSettingsButton.Enable = 'off';
            app.calculateFilterButton.Enable = 'off';
            app.loadIRButton.Enable = 'off';
            app.loadIRButton.Tooltip = ['select .mat,.wav or .txt file from where the IR is read, file can contain multiple IRs']
            app.saveResultsButton.Enable = 'off';
            app.saveResultsButton.Tooltip = ['the resulting equalizer curves can be saved in different formats .jpg, .png, .tif, .pdf, .eps'];
            app.saveavgIRButton.Enable = 'off';
            app.saveavgIRButton.Tooltip = ['the averaged IR is saved as .wav file.'];
            app.saveIRsButton.Enable = 'off'
            app.saveIRsButton.Tooltip = ['the measured IRs are saved as .txt file. Each IR is stored as columnvector.'];
            app.saveFiltercoeffButton.Enable = 'off';
            app.saveFiltercoeffButton.Tooltip = ['filter coefficients according to chosen filter type \n order of coefficients: b0,b1,b2,a0,a1,a2,fir.'];
            app.filtertypeDropDown.Items = {'parallel - IIR', 'FIR', 'linearphase - FIR'}
            app.FIRtapsEditField.Visible = 'off';
            app.FIRtapsEditFieldLabel.Visible = 'off';
            app.loadmicCorrection.Enable = 'off';
            app.defaultPoles = logspace(log10(15.625),log10(32000),265);
            app.lowestpoleinHzDropDown.Items = compose("%.2f",app.defaultPoles(:,1:145));
            app.highestpoleinHzDropDown.Items = compose("%.2f",app.defaultPoles(:,145:end));
            app.lowestpoleinHzDropDown.Value = app.lowestpoleinHzDropDown.Items(33);
            app.highestpoleinHzDropDown.Value = app.highestpoleinHzDropDown.Items(97);
            app.octavesmoothingDropDown.Items = compose("%.0f",[1,2,3,6,12,24,48]);
            app.octavesmoothingDropDown.Value = app.octavesmoothingDropDown.Items(3);
            app.IRLoaded = false;

            % set labels and titel of Axis
            set(app.UIIR_measured, 'Fontsize',14);
            xlabel(app.UIIR_measured, 'Time in samples', Interpreter='latex');
            ylabel(app.UIIR_measured, 'Amplitude', Interpreter='latex');
            title(app.UIIR_measured, 'measured Room Impulse Responses', Interpreter='latex');
            app.UIIR_measured.TickLabelInterpreter='latex';
            set(app.UIAxes, 'Fontsize',14);
            xlabel(app.UIAxes, 'Frequency in Hz', Interpreter='latex');
            xticks(app.UIAxes, [20,50,100, 200, 500, 1000, 2000, 5000, 10000, 20000]);
            ylabel(app.UIAxes, 'Magnitude in dB', Interpreter='latex');
            title(app.UIAxes, 'Results', Interpreter='latex');
            app.UIAxes.TickLabelInterpreter='latex';
            semilogx(app.UIAxes, 0);
            axis(app.UIAxes, [10 25000 -40 30]);
        end

        % Button pushed function: readSettingsButton
        function readSettingsButtonButtonPushed(app, event)
            app.fs = str2num(app.SamplingRate.Value);
            app.RT60 = app.estimatedRT60.Value;
            app.sweeplength = app.Sweeplength.Value;
            app.numberMeasurements = app.IRmeasurements.Value;
            app.LC = app.lowCutTargetResponse.Value;
            app.inputDevice = app.audioDeviceInfo.input(find(strcmp({app.audioDeviceInfo.input.Name},app.InputDevice.Value)==1)).ID;
            app.outputDevice = app.audioDeviceInfo.output(find(strcmp({app.audioDeviceInfo.output.Name},app.OutputDevice.Value)==1)).ID;
            app.micCorr = ones(app.fs,1);
            app.measurementButton.Enable = 'on';
            app.loadIRButton.Enable = 'on';
            app.readSettingsButton.Enable = 'off';
        end

        % Button pushed function: measurementButton
        function measurementButtonPushed(app, event)
            app.loadIRButton.Enable = 'off';
            app.measurementButton.Enable = 'off';
            Measurement(app);
            app.calculateFilterButton.Enable = 'on';
            app.loadmicCorrection.Enable = 'on';
            app.loadTargetCurve.Enable = 'on';
            app.windowlength.Tooltip = ['by default the window length is set to quarter of the impulse response '];
        end

        % Button pushed function: saveEQButton
        function saveEQButtonButtonPushed(app, event)
            [file,path] = uiputfile('*.wav', 'Save Wav File');
            filename = fullfile(path, file);
            audiowrite(filename, 0.99*app.equalizer/max(app.equalizer),app.fs);
            uiwait(msgbox('EQ curve was saved as .wav file.','modal'));
        end

        % Button pushed function: resetSettingsButton
        function resetSettingsButtonButtonPushed(app, event)
            app.fs = 0;                     
            app.RT60 = 0;                    
            app.sweeplength = 0;           
            app.numberMeasurements = 0;    
            app.LC = 0;        
            app.filtersperOct = 0;                          
            app.sweep = [];                   
            app.inverseSweep = [];           
            app.newStartFrequency = 0;       
            app.multiIR = [];                
            app.equalizer = [];   
            app.winlength = 0;
            app.windowlength.Value = 4000;
            app.measurementButton.Enable = 'off';
            app.loadIRButton.Enable = 'off';
            cla(app.UIIR_measured);
            cla(app.UIAxes);
            app.readSettingsButton.Enable = 'on';
            app.saveEQButton.Enable = 'off';
            app.saveResultsButton.Enable = 'off';
            app.saveavgIRButton.Enable = 'off';
            app.saveIRsButton.Enable = 'off';
            app.saveFiltercoeffButton.Enable = 'off';
            app.calculateFilterButton.Enable = 'off';
            app.loadmicCorrection.Enable = 'off';
            clearvars;
        end

        % Button pushed function: saveResultsButton
        function saveResultsButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename,filepath] = uiputfile(filter,'Save Plot');
            if ischar(filename)
                exportgraphics(app.UIAxes,[filepath filename]);
            end
            uiwait(msgbox('Results were saved.','modal'));
        end

        % Callback function: PlotData1, PlotData2, PlotData3, PlotData4, 
        % ...and 1 other component
        function calculateFilterButtonButtonPushed(app, event)
            cla(app.UIAxes);
%             set(app.UIAxes, 'Fontsize',14);
%             xlabel(app.UIAxes, 'Frequency in Hz', Interpreter='latex');
%             xticks(app.UIAxes, [20,50,100, 200, 500, 1000, 2000, 5000, 10000, 20000]);
%             ylabel(app.UIAxes, 'Magnitude in dB', Interpreter='latex');
%             title(app.UIAxes, 'Results', Interpreter='latex');
%             app.UIAxes.TickLabelInterpreter='latex';
%             axis(app.UIAxes, [10 25000 -40 30]);
            filterCalculation(app);
        end

        % Button pushed function: loadIRButton
        function loadIRButtonPushed(app, event)
            [filename, pathname] = uigetfile("*.mp3;*.wav;*.mat;*.txt");
            fullName = fullfile(pathname, filename);
            [~,~,ext] = fileparts(filename);
            if strcmp(ext,'.mat')
                tmpC = struct2cell(load(fullName));
                plotTimeIR(app,tmpC{1},true);
            end
            if strcmp(ext,'.wav')
                [y,fs_new] = audioread(fullName);
                app.fs = fs_new;
                plotTimeIR(app,y,true);
            end
            if strcmp(ext,'.txt')
                plotTimeIR(app,readmatrix(fullName),true);
            end
            app.measurementButton.Enable = 'off';
            app.measurementButton.Enable = 'off';
            app.loadIRButton.Enable = 'off';
            app.calculateFilterButton.Enable = 'on';
            app.loadmicCorrection.Enable = 'on';
            app.IRLoaded = true;
        end

        % Button pushed function: saveavgIRButton
        function saveavgIRButtonPushed(app, event)
            [file,path] = uiputfile('*.wav', 'Save Wav File');
            filename = fullfile(path, file);
            audiowrite(filename, 0.99*app.ir_norm/max(app.ir_norm),app.fs);
            uiwait(msgbox('IR was saved as .wav file.','modal'));
        end

        % Button pushed function: saveFiltercoeffButton
        function saveFiltercoeffButtonPushed(app, event)
            if app.Am == 1
                len = max(size(app.FIR,1));
                coeff = zeros(len,1);
                coeff(1:size(app.FIR(:,1),1),1) = app.FIR(:,1);
            else
                Am = app.Am';
                Bm = app.Bm';
                FIR = app.FIR';
                len = max([size(Am,1),size(Bm,1),size(FIR,1)]);
                coeff = zeros(len+1,6);
                coeff(1:size(Bm(:,1),1),1) = Bm(:,1);
                coeff(1:size(Bm(:,2),1),2) = Bm(:,2); 
                coeff(1:size(Bm(:,2),1),3) = 0;
                coeff(1:size(Am(:,1),1),4) = Am(:,1);
                coeff(1:size(Am(:,2),1),5) = Am(:,2);
                coeff(1:size(Am(:,3),1),6) = Am(:,3);
                coeff(len+1,1) = FIR;
            end
            [file,path] = uiputfile('filterCoefficients.csv');
            %save(fullfile(path, file), 'coeff', '-ascii')
            csvwrite(fullfile(path, file),coeff);
            uiwait(msgbox('filter coefficients were saved as .csv','modal'));
        end

        % Button pushed function: saveIRsButton
        function saveIRsButtonPushed(app, event)
            IRArray = app.multiIR(1:app.IRlen,:);
            [file,path] = uiputfile('all_measurements.txt');
            save(fullfile(path, file), 'IRArray', '-ascii')
            uiwait(msgbox('IRs were saved as .txt.','modal'));
        end

        % Value changed function: filtertypeDropDown
        function filtertypeDropDownValueChanged(app, event)
            value = app.filtertypeDropDown.Value;
            if ~strcmp(value,'parallel - IIR')
                app.FIRtapsEditField.Visible = 'on';
                app.FIRtapsEditFieldLabel.Visible = 'on';
                app.windowlength.Visible = 'off';
                app.windowlengthinsamplesLabel.Visible = 'off';
            else
                app.FIRtapsEditField.Visible = 'off';
                app.FIRtapsEditFieldLabel.Visible = 'off';
                app.windowlength.Visible = 'on';
                app.windowlengthinsamplesLabel.Visible = 'on';
            end
        end

        % Button pushed function: loadmicCorrection
        function loadmicCorrectionButtonPushed(app, event)
            [filename, pathname] = uigetfile("*.txt");
            fullName = fullfile(pathname, filename);
            file = importdata(fullName);
            data = file.data;
            f_old = data(:,1);
            f_new = linspace(0,app.fs/2-1,app.fs/2)';
            ref = interp1(f_old, data(:,2), f_new, 'linear', 'extrap');
            ref = ref*(-1);
            app.micCorr = db2mag([ref;flipud(ref)]);                                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IR_Equalisation = uifigure('Visible', 'off');
            app.IR_Equalisation.Name = 'UI Figure';
            app.Menu = uimenu(app.IR_Equalisation);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.IR_Equalisation);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIIR_measured = uiaxes(app.IR_Equalisation);
            title(app.UIIR_measured, 'Title')
            xlabel(app.UIIR_measured, 'X')
            ylabel(app.UIIR_measured, 'Y')
            app.Label = uilabel(app.IR_Equalisation);
            app.Label.Text = '';
            app.samplingrateLabel = uilabel(app.IR_Equalisation);
            app.samplingrateLabel.Text = 'sampling rate';
            app.SamplingRate = uidropdown(app.IR_Equalisation);
            app.SamplingRate.Items = {'5512', '11026', '22050', '44100', '48000', '88200', '96000'};
            app.SamplingRate.Value = '44100';
            app.estimatedRT60insLabel = uilabel(app.IR_Equalisation);
            app.estimatedRT60insLabel.Text = 'estimated RT60 in s';
            app.estimatedRT60 = uieditfield(app.IR_Equalisation, 'numeric');
            app.estimatedRT60.Limits = [0.2 2];
            app.estimatedRT60.Value = 0.2;
            app.sweeplengthinsLabel = uilabel(app.IR_Equalisation);
            app.sweeplengthinsLabel.Text = 'sweep length in s';
            app.Sweeplength = uieditfield(app.IR_Equalisation, 'numeric');
            app.Sweeplength.Limits = [0.5 4];
            app.Sweeplength.Value = 0.5;
            app.nofIRmeasurementsLabel = uilabel(app.IR_Equalisation);
            app.nofIRmeasurementsLabel.Text = 'n of IR measurements';
            app.IRmeasurements = uieditfield(app.IR_Equalisation, 'numeric');
            app.IRmeasurements.Limits = [1 20];
            app.IRmeasurements.Value = 2;
            app.LowCutfortargetresponseinHZLabel = uilabel(app.IR_Equalisation);
            app.LowCutfortargetresponseinHZLabel.Text = 'low cut for target response in Hz';
            app.lowCutTargetResponse = uieditfield(app.IR_Equalisation, 'numeric');
            app.lowCutTargetResponse.Limits = [20 500];
            app.lowCutTargetResponse.Value = 40;
            app.nfiltersperOctaveLabel = uilabel(app.IR_Equalisation);
            app.nfiltersperOctaveLabel.Text = 'n filters per octave';
            app.filtersperOctave = uieditfield(app.IR_Equalisation, 'numeric');
            app.filtersperOctave.Limits = [1 48];
            app.filtersperOctave.Value = 3;
            app.readSettingsButton = uibutton(app.IR_Equalisation, 'push');
            app.readSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @readSettingsButtonButtonPushed, true);
            app.readSettingsButton.Text = 'read Settings';
            app.InputDeviceLabel = uilabel(app.IR_Equalisation);
            app.InputDeviceLabel.Text = 'input device';
            app.InputDevice = uidropdown(app.IR_Equalisation);
            app.InputDevice.Items = {};
            app.InputDevice.Value = {};
            app.OutputDeviceLabel = uilabel(app.IR_Equalisation);
            app.OutputDeviceLabel.Text = 'output device';
            app.OutputDevice = uidropdown(app.IR_Equalisation);
            app.OutputDevice.Items = {};
            app.OutputDevice.Value = {};
            app.measurementButton = uibutton(app.IR_Equalisation, 'push');
            app.measurementButton.ButtonPushedFcn = createCallbackFcn(app, @measurementButtonPushed, true);
            app.measurementButton.Text = 'measurement';
            app.SettingsLabel = uilabel(app.IR_Equalisation);
            app.SettingsLabel.Text = 'Settings';
            app.saveEQButton = uibutton(app.IR_Equalisation, 'push');
            app.saveEQButton.ButtonPushedFcn = createCallbackFcn(app, @saveEQButtonButtonPushed, true);
            app.saveEQButton.Text = 'save EQ';
            app.resetSettingsButton = uibutton(app.IR_Equalisation, 'push');
            app.resetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @resetSettingsButtonButtonPushed, true);
            app.resetSettingsButton.Text = 'reset Settings';
            app.saveResultsButton = uibutton(app.IR_Equalisation, 'push');
            app.saveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @saveResultsButtonPushed, true);
            app.saveResultsButton.Text = 'save Results';
            app.calculateFilterButton = uibutton(app.IR_Equalisation, 'push');
            app.calculateFilterButton.ButtonPushedFcn = createCallbackFcn(app, @calculateFilterButtonButtonPushed, true);
            app.calculateFilterButton.Text = 'calculate Filter';
            app.loadIRButton = uibutton(app.IR_Equalisation, 'push');
            app.loadIRButton.ButtonPushedFcn = createCallbackFcn(app, @loadIRButtonPushed, true);
            app.loadIRButton.Text = 'load IR';
            app.lowestpoleinHzDropDownLabel = uilabel(app.IR_Equalisation);
            app.lowestpoleinHzDropDownLabel.Text = 'lowest pole in Hz';
            app.lowestpoleinHzDropDown = uidropdown(app.IR_Equalisation);
            app.highestpoleinHzDropDownLabel = uilabel(app.IR_Equalisation);
            app.highestpoleinHzDropDownLabel.Text = 'highest pole in Hz';
            app.highestpoleinHzDropDown = uidropdown(app.IR_Equalisation);
            app.saveavgIRButton = uibutton(app.IR_Equalisation, 'push');
            app.saveavgIRButton.ButtonPushedFcn = createCallbackFcn(app, @saveavgIRButtonPushed, true);
            app.saveavgIRButton.Text = 'save avg. IR';
            app.windowlengthinsamplesLabel = uilabel(app.IR_Equalisation);
            app.windowlengthinsamplesLabel.Text = 'window length in samples';
            app.windowlength = uieditfield(app.IR_Equalisation, 'numeric');
            app.windowlength.Limits = [20 192000];
            app.windowlength.ValueDisplayFormat = '%.0f';
            app.windowlength.Value = 44100;
            app.saveFiltercoeffButton = uibutton(app.IR_Equalisation, 'push');
            app.saveFiltercoeffButton.ButtonPushedFcn = createCallbackFcn(app, @saveFiltercoeffButtonPushed, true);
            app.saveFiltercoeffButton.Text = 'save Filter coeff.';
            app.saveIRsButton = uibutton(app.IR_Equalisation, 'push');
            app.saveIRsButton.ButtonPushedFcn = createCallbackFcn(app, @saveIRsButtonPushed, true);
            app.saveIRsButton.Text = 'save IRs';
            app.filtertypeDropDownLabel = uilabel(app.IR_Equalisation);
            app.filtertypeDropDownLabel.Text = 'filter type';
            app.filtertypeDropDown = uidropdown(app.IR_Equalisation);
            app.filtertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @filtertypeDropDownValueChanged, true);
            app.FIRtapsEditFieldLabel = uilabel(app.IR_Equalisation);
            app.FIRtapsEditFieldLabel.Text = 'FIR taps';
            app.FIRtapsEditField = uieditfield(app.IR_Equalisation, 'numeric');
            app.FIRtapsEditField.Limits = [10 16384];
            app.FIRtapsEditField.Value = 4096;
            app.octavesmoothingDropDownLabel = uilabel(app.IR_Equalisation);
            app.octavesmoothingDropDownLabel.Text = 'octave smoothing';
            app.octavesmoothingDropDown = uidropdown(app.IR_Equalisation);
            app.loadmicCorrection = uibutton(app.IR_Equalisation, 'push');
            app.loadmicCorrection.ButtonPushedFcn = createCallbackFcn(app, @loadmicCorrectionButtonPushed, true);
            app.loadmicCorrection.Text = 'load mic Correction';
            app.PlotData4 = uicheckbox(app.IR_Equalisation);
            app.PlotData4.ValueChangedFcn = createCallbackFcn(app, @calculateFilterButtonButtonPushed, true);
            app.PlotData4.Text = '';
            app.PlotData4.Value = true;
            app.Label_2 = uilabel(app.IR_Equalisation);
            app.Label_2.Text = '';
            app.PlotData1 = uicheckbox(app.IR_Equalisation);
            app.PlotData1.ValueChangedFcn = createCallbackFcn(app, @calculateFilterButtonButtonPushed, true);
            app.PlotData1.Text = '';
            app.PlotData1.Value = true;
            app.Label_3 = uilabel(app.IR_Equalisation);
            app.Label_3.Text = '';
            app.PlotData2 = uicheckbox(app.IR_Equalisation);
            app.PlotData2.ValueChangedFcn = createCallbackFcn(app, @calculateFilterButtonButtonPushed, true);
            app.PlotData2.Text = '';
            app.PlotData2.Value = true;
            app.Label_4 = uilabel(app.IR_Equalisation);
            app.Label_4.Text = '';
            app.PlotData3 = uicheckbox(app.IR_Equalisation);
            app.PlotData3.ValueChangedFcn = createCallbackFcn(app, @calculateFilterButtonButtonPushed, true);
            app.PlotData3.Text = '';
            app.PlotData3.Value = true;
            % Show the figure after all components are created
            app.IR_Equalisation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALEv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IR_Equalisation)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IR_Equalisation)
        end
    end
end